classdef Gender_Recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenderRecognitionUIFigure  matlab.ui.Figure
        TabGroup2                  matlab.ui.container.TabGroup
        BasicTab                   matlab.ui.container.Tab
        GenderRecognitionLabel     matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        Button_2                   matlab.ui.control.Button
        EditField_3Label           matlab.ui.control.Label
        EditField_3                matlab.ui.control.EditField
        Button_3                   matlab.ui.control.Button
        CheckBox                   matlab.ui.control.CheckBox
        Panel_2                    matlab.ui.container.Panel
        sEditFieldLabel            matlab.ui.control.Label
        sEditField                 matlab.ui.control.NumericEditField
        Button_4                   matlab.ui.control.Button
        SwitchLabel                matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Button                     matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        EditField_2                matlab.ui.control.EditField
        EditField_2Label           matlab.ui.control.Label
        PreferrenceTab             matlab.ui.container.Tab
        Panel_3                    matlab.ui.container.Panel
        DropDownLabel              matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        DropDown_2Label            matlab.ui.control.Label
        DropDown_2                 matlab.ui.control.DropDown
        LiteEnhancementTab         matlab.ui.container.Tab
        Panel_4                    matlab.ui.container.Panel
        Button_5                   matlab.ui.control.Button
        EditField_4Label           matlab.ui.control.Label
        EditField_4                matlab.ui.control.EditField
        Button_6                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        SNREditFieldLabel          matlab.ui.control.Label
        SNREditField               matlab.ui.control.NumericEditField
        dBLabel                    matlab.ui.control.Label
        Panel_5                    matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        DropDown_3                 matlab.ui.control.DropDown
        Button_8                   matlab.ui.control.Button
        Button_9                   matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global time GMM_GFCC GMM_PLP fb classifier feature SNR method Age;
            time = 3;
            SNR = 5;
            Age = 0;
            method = 'Spectral Subtraction';
            GMM_GFCC = load('Gama\GMM_ch_GFCC_64_1_48_0_8k_d_dd_1.mat');  %加载两个GMM模型
            GMM_PLP = load('Gama\PLP_GMM_3_48.mat');
            classifier = app.DropDown_2.Value;
            feature = app.DropDown.Value;
            fbankmake;
            fb = load('filterbank.mat');
        end

        % Button pushed function: Button_2
        function ButtonPushed(app, event)
            global d sr cepDpDD d_nozero;
            [file,path] = uigetfile('*.*','Select a Audio');
            if path == 0
                errordlg('ERROR! No file selected!');
                return;
            end
            set(app.EditField_3,'Value',[path file]);
            [d,sr] = audioread([path file]);
            
            set(app.EditField,'Value','Analysing');
            % feature extraction
            [vs,~] = vadsohn(d,sr);
            d_nozero = d(1:length(vs));
            d_nozero(vs==0) = [];            %去掉silence部分
            
            % Calculate 12th order PLP features without RASTA
            [cep, ~] = rastaplp(d_nozero, sr, 0, 12);
            del = deltas(cep);
            ddel = deltas(deltas(cep,5),5);
            cepDpDD = [cep',del',ddel'];

            set(app.EditField,'Value','ok');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global d sr;
            sound(d,sr);
        end

        % Value changed function: sEditField
        function sEditFieldValueChanged(app, event)
            global time;
            time = app.sEditField.Value;            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global time d sr cepDpDD d_nozero;
            recObj = audiorecorder(16000, 16, 1);
            recordblocking(recObj, time);
            set(app.Switch,'Value','Off')
            play(recObj);
            d = getaudiodata(recObj);
            sr = 16000;
            
            set(app.EditField,'Value','Analysing');
            % feature extraction
            [vs,~] = vadsohn(d,sr);
            d_nozero = d(1:length(vs));
            d_nozero(vs==0) = [];            %去掉silence部分
            
            % Calculate 12th order PLP features without RASTA
            [cep, ~] = rastaplp(d_nozero, sr, 0, 12);
            del = deltas(cep);
            ddel = deltas(deltas(cep,5),5);
            cepDpDD = [cep',del',ddel'];
            set(app.EditField,'Value','ok');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global d sr;
            sound(d,sr);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global feature;
            feature = app.DropDown.Value;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            global classifier feature;
            classifier = app.DropDown_2.Value;
            switch classifier
                case '神经网络'
                    set(app.DropDown,'Items',{'GFCC','PLP'});
                    feature = 'MFCC';
                case 'GMM'
                    set(app.DropDown,'Items',{'GFCC','PLP'});
                    feature = 'GFCC';
                case 'Gauss'
                    set(app.DropDown,'Items',{'Pitch'});
                case 'Comprehensive'
                    set(app.DropDown,'Items',{'GFCC+PLP+Pitch'});
            end
        end

        % Button pushed function: Button
        function Button_2Pushed(app, event)
            global feature classifier d sr GMM_PLP GMM_GFCC d_nozero Age;
            global cepDpDD;                 % features
            gender = NaN;
            if Age == 0
                switch classifier
                    case '神经网络'
                        switch feature                                  
                            case 'PLP'
                                datacolumns = {'PLP_cep1','PLP_cep2','PLP_cep3','PLP_cep4','PLP_cep5','PLP_cep6',...
                                               'PLP_cep7','PLP_cep8','PLP_cep9','PLP_cep10','PLP_cep11','PLP_cep12','PLP_cep13',...
                                               'PLP_deltas1','PLP_deltas2','PLP_deltas3','PLP_deltas4','PLP_deltas5','PLP_deltas6',...
                                               'PLP_deltas7','PLP_deltas8','PLP_deltas9','PLP_deltas10','PLP_deltas11','PLP_deltas12','PLP_deltas13',...
                                               'PLP_ddeltas1','PLP_ddeltas2','PLP_ddeltas3','PLP_ddeltas4','PLP_ddeltas5','PLP_ddeltas6',...
                                               'PLP_ddeltas7','PLP_ddeltas8','PLP_ddeltas9','PLP_ddeltas10','PLP_ddeltas11','PLP_ddeltas12','PLP_ddeltas13'};
                                Test = table(cepDpDD(:,1),cepDpDD(:,2),cepDpDD(:,3),cepDpDD(:,4),cepDpDD(:,5),cepDpDD(:,6),cepDpDD(:,7),...
                                             cepDpDD(:,8),cepDpDD(:,9),cepDpDD(:,10),cepDpDD(:,11),cepDpDD(:,12),cepDpDD(:,13),...
                                             cepDpDD(:,14),cepDpDD(:,15),cepDpDD(:,16),cepDpDD(:,17),cepDpDD(:,18),cepDpDD(:,19),cepDpDD(:,20),...
                                             cepDpDD(:,21),cepDpDD(:,22),cepDpDD(:,23),cepDpDD(:,24),cepDpDD(:,25),cepDpDD(:,26),...
                                             cepDpDD(:,27),cepDpDD(:,28),cepDpDD(:,29),cepDpDD(:,30),cepDpDD(:,31),cepDpDD(:,32),cepDpDD(:,33),...
                                             cepDpDD(:,34),cepDpDD(:,35),cepDpDD(:,36),cepDpDD(:,37),cepDpDD(:,38),cepDpDD(:,39),...
                                            'VariableNames',datacolumns);
                                Test=table2array(Test)';
                                [~,m]=size(Test);
                                output=zeros(1,m);
                                
                                for i=1:m
                                    if py.test.nn_test_plp(Test(:,i)')==0
                                        output(1,i)=0;
                                    else
                                        output(1,i)=1;
                                    end
                                end
                                if 2*sum(output)>length(Test)
                                    gender=1;
                                else
                                    gender=0;
                                end
                            case 'GFCC'
                                [sp_GC_FT,dtm1,dtm2] =GFCC(d_nozero,sr);
                                Test = [sp_GC_FT,dtm1,dtm2]';
                                [~,m]=size(Test);
                                output=zeros(1,m);
                                
                                for i=1:m
                                    if py.test.nn_test_gfcc(Test(:,i)')==0
                                        output(1,i)=0;
                                    else
                                        output(1,i)=1;
                                    end
                                end
                                if 2*sum(output)>length(Test)
                                    gender=1;
                                else
                                    gender=0;
                                end
                        end
                    case 'GMM'
                        switch feature
                            case 'GFCC'
                                gender = GFCC_result(d_nozero,sr,GMM_GFCC.GMM_GFCC);
                            case 'PLP'
                                gender = PLP_result(d_nozero,sr,GMM_PLP.GMM_PLP);
                        end
                    case 'Gauss'
                        gender = gender_pitch(d,sr);
                    case 'Comprehensive'
                        % PLP
                        datacolumns = {'PLP_cep1','PLP_cep2','PLP_cep3','PLP_cep4','PLP_cep5','PLP_cep6',...
                                               'PLP_cep7','PLP_cep8','PLP_cep9','PLP_cep10','PLP_cep11','PLP_cep12','PLP_cep13',...
                                               'PLP_deltas1','PLP_deltas2','PLP_deltas3','PLP_deltas4','PLP_deltas5','PLP_deltas6',...
                                               'PLP_deltas7','PLP_deltas8','PLP_deltas9','PLP_deltas10','PLP_deltas11','PLP_deltas12','PLP_deltas13',...
                                               'PLP_ddeltas1','PLP_ddeltas2','PLP_ddeltas3','PLP_ddeltas4','PLP_ddeltas5','PLP_ddeltas6',...
                                               'PLP_ddeltas7','PLP_ddeltas8','PLP_ddeltas9','PLP_ddeltas10','PLP_ddeltas11','PLP_ddeltas12','PLP_ddeltas13'};
                        Test = table(cepDpDD(:,1),cepDpDD(:,2),cepDpDD(:,3),cepDpDD(:,4),cepDpDD(:,5),cepDpDD(:,6),cepDpDD(:,7),...
                                     cepDpDD(:,8),cepDpDD(:,9),cepDpDD(:,10),cepDpDD(:,11),cepDpDD(:,12),cepDpDD(:,13),...
                                     cepDpDD(:,14),cepDpDD(:,15),cepDpDD(:,16),cepDpDD(:,17),cepDpDD(:,18),cepDpDD(:,19),cepDpDD(:,20),...
                                     cepDpDD(:,21),cepDpDD(:,22),cepDpDD(:,23),cepDpDD(:,24),cepDpDD(:,25),cepDpDD(:,26),...
                                     cepDpDD(:,27),cepDpDD(:,28),cepDpDD(:,29),cepDpDD(:,30),cepDpDD(:,31),cepDpDD(:,32),cepDpDD(:,33),...
                                     cepDpDD(:,34),cepDpDD(:,35),cepDpDD(:,36),cepDpDD(:,37),cepDpDD(:,38),cepDpDD(:,39),...
                                    'VariableNames',datacolumns);
                        Test=table2array(Test)';
                        [~,m]=size(Test);
                        output=zeros(1,m);
                        
                        for i=1:m
                            if py.test.nn_test_plp(Test(:,i)')==0
                                output(1,i)=0;
                            else
                                output(1,i)=1;
                            end
                        end
                        if 2*sum(output)>length(Test)
                            result_PLP=1;
                        else
                            result_PLP=0;
                        end
                        
                        result_GFCC = GFCC_result(d_nozero,sr,GMM_GFCC.GMM_GFCC);
                        result_Pitch = gender_pitch(d,sr);
                        SNR = voiceSNR(d_nozero,sr);
                        result = [result_PLP,result_GFCC,result_Pitch];
                        if SNR>10
                           if sum(result)>=2
                             gender=1;
                           else
                             gender=0;
                           end
                        else
                            if sum(result)>=2 ||result_Pitch==1
                                gender=1;
                            else
                                gender=0;
                            end
                        end
                end
            else
                % PLP-Child
                datacolumns = {'PLP_cep1','PLP_cep2','PLP_cep3','PLP_cep4','PLP_cep5','PLP_cep6',...
                                       'PLP_cep7','PLP_cep8','PLP_cep9','PLP_cep10','PLP_cep11','PLP_cep12','PLP_cep13',...
                                       'PLP_deltas1','PLP_deltas2','PLP_deltas3','PLP_deltas4','PLP_deltas5','PLP_deltas6',...
                                       'PLP_deltas7','PLP_deltas8','PLP_deltas9','PLP_deltas10','PLP_deltas11','PLP_deltas12','PLP_deltas13',...
                                       'PLP_ddeltas1','PLP_ddeltas2','PLP_ddeltas3','PLP_ddeltas4','PLP_ddeltas5','PLP_ddeltas6',...
                                       'PLP_ddeltas7','PLP_ddeltas8','PLP_ddeltas9','PLP_ddeltas10','PLP_ddeltas11','PLP_ddeltas12','PLP_ddeltas13'};
                Test = table(cepDpDD(:,1),cepDpDD(:,2),cepDpDD(:,3),cepDpDD(:,4),cepDpDD(:,5),cepDpDD(:,6),cepDpDD(:,7),...
                             cepDpDD(:,8),cepDpDD(:,9),cepDpDD(:,10),cepDpDD(:,11),cepDpDD(:,12),cepDpDD(:,13),...
                             cepDpDD(:,14),cepDpDD(:,15),cepDpDD(:,16),cepDpDD(:,17),cepDpDD(:,18),cepDpDD(:,19),cepDpDD(:,20),...
                             cepDpDD(:,21),cepDpDD(:,22),cepDpDD(:,23),cepDpDD(:,24),cepDpDD(:,25),cepDpDD(:,26),...
                             cepDpDD(:,27),cepDpDD(:,28),cepDpDD(:,29),cepDpDD(:,30),cepDpDD(:,31),cepDpDD(:,32),cepDpDD(:,33),...
                             cepDpDD(:,34),cepDpDD(:,35),cepDpDD(:,36),cepDpDD(:,37),cepDpDD(:,38),cepDpDD(:,39),...
                            'VariableNames',datacolumns);
                Test=table2array(Test)';
                [~,m]=size(Test);
                output=zeros(1,m);
                
                for i=1:m
                    if py.test.nn_test_plp_kid(Test(:,i)')==0
                        output(1,i)=0;
                    else
                        output(1,i)=1;
                    end
                end
                if 2*sum(output)>length(Test)
                    gender=1;
                else
                    gender=0;
                end
            end
            if(gender)
                set(app.EditField_2,'Value','Male');
                tts('这是个男孩子','Microsoft Huihui Desktop');
            else
                set(app.EditField_2,'Value','Female');
                tts('这是个女孩子','Microsoft Huihui Desktop');
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            global d1 sr1;
            [file,path] = uigetfile('*.*','Select a Audio');
            if path == 0
                errordlg('ERROR! No file selected!');
                return;
            end
            set(app.EditField_4,'Value',[path file]);
            [d1,sr1] = audioread([path file]);
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            global d1 sr1;
            sound(d1,sr1);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            global SNR d_n sr1 d1;
            [d_n,~] = noisegen(d1,SNR);
            sound(d_n,sr1);
        end

        % Value changed function: SNREditField
        function SNREditFieldValueChanged(app, event)
            global SNR;
            SNR = app.SNREditField.Value;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            global d_n sr1 d_R fb method;
            switch method
                case 'Spectral Subtraction'
                    d_R = specsub(d_n,sr1);
                    sound(d_R,sr1);
                case 'RASTA'
                    d_R = envexpand(d_n,sr1,fb);
                    sound(d_R,sr1);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            global d_R d1 sr1 d_n;
            figure;
            subplot(311);
            plot(d1);
            title('Original');
            subplot(312);
            plot(d_n);
            title('Voice with noise');
            subplot(313);
            plot(d_R);
            title('Noised Canceled');
            figure;
            subplot(311);
            specgram(d1, 256, sr1);
            title('Original Spectrogram');
            subplot(312);
            specgram(d_n, 256, sr1);
            title('Noised Spectrogram');
            subplot(313);
            specgram(d_R, 256, sr1);
            title('Noised Canceled Spectrogram');

        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            global method;
            method = app.DropDown_3.Value;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            global Age;
            Age = app.CheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenderRecognitionUIFigure = uifigure;
            app.GenderRecognitionUIFigure.Name = 'Gender Recognition';
            app.TabGroup2 = uitabgroup(app.GenderRecognitionUIFigure);
            app.BasicTab = uitab(app.TabGroup2);
            app.BasicTab.Title = 'Basic';
            app.GenderRecognitionLabel = uilabel(app.BasicTab);
            app.GenderRecognitionLabel.Text = 'Gender Recognition';
            app.Panel = uipanel(app.BasicTab);
            app.Panel.Title = '语音文件识别';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Text = '…';
            app.EditField_3Label = uilabel(app.Panel);
            app.EditField_3Label.Text = '文件路径';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '播放';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '童声';
            app.Panel_2 = uipanel(app.BasicTab);
            app.Panel_2.Title = '录音识别';
            app.sEditFieldLabel = uilabel(app.Panel_2);
            app.sEditFieldLabel.Text = '时长(s)';
            app.sEditField = uieditfield(app.Panel_2, 'numeric');
            app.sEditField.Limits = [0 10];
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @sEditFieldValueChanged, true);
            app.sEditField.Value = 3;
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '播放';
            app.SwitchLabel = uilabel(app.Panel_2);
            app.SwitchLabel.Text = '录音开关';
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Button = uibutton(app.BasicTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button.Text = '开始识别';
            app.EditField = uieditfield(app.BasicTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.BasicTab, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2Label = uilabel(app.BasicTab);
            app.EditField_2Label.Text = '识别结果:';
            app.PreferrenceTab = uitab(app.TabGroup2);
            app.PreferrenceTab.Title = 'Preferrence';
            app.Panel_3 = uipanel(app.PreferrenceTab);
            app.Panel_3.Title = '方法选择';
            app.DropDownLabel = uilabel(app.Panel_3);
            app.DropDownLabel.Text = '特征';
            app.DropDown = uidropdown(app.Panel_3);
            app.DropDown.Items = {'PLP+GFCC+Pitch', 'PLP', 'GFCC', 'Pitch'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'PLP+GFCC+Pitch';
            app.DropDown_2Label = uilabel(app.Panel_3);
            app.DropDown_2Label.Text = '分类器';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Items = {'Comprehensive', 'GMM', '神经网络', 'Gauss'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Comprehensive';
            app.LiteEnhancementTab = uitab(app.TabGroup2);
            app.LiteEnhancementTab.Title = 'Lite Enhancement';
            app.Panel_4 = uipanel(app.LiteEnhancementTab);
            app.Panel_4.Title = '语音处理';
            app.Button_5 = uibutton(app.Panel_4, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '…';
            app.EditField_4Label = uilabel(app.Panel_4);
            app.EditField_4Label.Text = '文件路径';
            app.EditField_4 = uieditfield(app.Panel_4, 'text');
            app.Button_6 = uibutton(app.Panel_4, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '播放';
            app.Button_7 = uibutton(app.Panel_4, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '加噪';
            app.SNREditFieldLabel = uilabel(app.Panel_4);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.Panel_4, 'numeric');
            app.SNREditField.ValueChangedFcn = createCallbackFcn(app, @SNREditFieldValueChanged, true);
            app.SNREditField.Value = 5;
            app.dBLabel = uilabel(app.Panel_4);
            app.dBLabel.Text = 'dB';
            app.Panel_5 = uipanel(app.LiteEnhancementTab);
            app.Panel_5.Title = '语音增强';
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = '方法';
            app.DropDown_3 = uidropdown(app.Panel_5);
            app.DropDown_3.Items = {'Spectral Subtraction', 'RASTA'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'Spectral Subtraction';
            app.Button_8 = uibutton(app.Panel_5, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '播放';
            app.Button_9 = uibutton(app.Panel_5, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '绘图';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Gender_Recognition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenderRecognitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenderRecognitionUIFigure)
        end
    end
end